classdef Diffuser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DiffuserGUI                     matlab.ui.Figure
        DiffusionmodelingPanel          matlab.ui.container.Panel
        InitialtemperatureEditFieldLabel  matlab.ui.control.Label
        InitialtemperatureEditField     matlab.ui.control.NumericEditField
        CoolingrateEditField_2Label     matlab.ui.control.Label
        CoolingrateEditField            matlab.ui.control.NumericEditField
        C1EditFieldLabel                matlab.ui.control.Label
        C1EditField                     matlab.ui.control.NumericEditField
        C2EditFieldLabel                matlab.ui.control.Label
        C2EditField                     matlab.ui.control.NumericEditField
        KLabel                          matlab.ui.control.Label
        StartModelingButton             matlab.ui.control.Button
        C3EditFieldLabel                matlab.ui.control.Label
        C3EditField                     matlab.ui.control.NumericEditField
        DiffusionprofileDropDownLabel   matlab.ui.control.Label
        DiffusionprofileDropDown        matlab.ui.control.DropDown
        XunitDropDownLabel              matlab.ui.control.Label
        XunitDropDown                   matlab.ui.control.DropDown
        SeeoptionsButton                matlab.ui.control.Button
        flatpeaktroughinprofileILCheckBox  matlab.ui.control.CheckBox
        minconcentrationLabel           matlab.ui.control.Label
        maxconcentrationLabel           matlab.ui.control.Label
        RatetypeDropDown                matlab.ui.control.DropDown
        sigmaLabel                      matlab.ui.control.Label
        TemperatureerrorEditFieldLabel  matlab.ui.control.Label
        TemperatureerrorEditField       matlab.ui.control.NumericEditField
        TrialsfortEditFieldLabel        matlab.ui.control.Label
        TrialsfortEditField             matlab.ui.control.NumericEditField
        whencoolingLabel                matlab.ui.control.Label
        usedinprofileKLLabel            matlab.ui.control.Label
        DatainputPanel                  matlab.ui.container.Panel
        ReaddataButton                  matlab.ui.control.Button
        SelectexcelButton               matlab.ui.control.Button
        SheetListBoxLabel               matlab.ui.control.Label
        SheetListBox                    matlab.ui.control.ListBox
        DiffusionCoefficientDPanel      matlab.ui.container.Panel
        ReadanotherDexcelButton         matlab.ui.control.Button
        OptionsDropDownLabel            matlab.ui.control.Label
        OptionsDropDown                 matlab.ui.control.DropDown
        D0EditFieldLabel                matlab.ui.control.Label
        D0EditField                     matlab.ui.control.NumericEditField
        HLabel                          matlab.ui.control.Label
        HEditField                      matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        data % Description
        filefullpath
        path
        file
        name
        D0
        H
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.filefullpath=0; app.path=0; app.file=0;
            [app.name, app.D0, app.H] = readDiffCoef();
            if ~isempty(app.D0)
                app.OptionsDropDown.Items=app.name;
                value = app.OptionsDropDown.Value;
                site=find(strcmp(app.name,value));
                app.D0EditField.Value=app.D0(site);
                app.HEditField.Value=app.H(site);
            end
        end

        % Button pushed function: SelectexcelButton
        function SelectexcelButtonPushed(app, event)
            filestyle={'*.xlsx';'*.xls'};
            [app.file, app.path]=uigetfile(filestyle,'Select a diffusion coefficient excel','MultiSelect','off');
            [~,b]=size(app.file);
            if  b==1 && app.file==0
                return;
            elseif ~iscell(app.file)
                [~,~,ext]=fileparts(app.file);
                switch ext
                    case '.xlsx'
                        app.filefullpath=[app.path app.file];
                    case '.xls'
                        app.filefullpath=[app.path app.file];
                    otherwise
                        return;
                end
            end
            [~, sheetname] = xlsfinfo(app.filefullpath);
            app.SheetListBox.Items=sheetname;
        end

        % Button pushed function: ReaddataButton
        function ReaddataButtonPushed(app, event)
            if ~app.filefullpath
                return
            end
            %read data
            sheet=app.SheetListBox.Value;
            app.data = xlsread(app.filefullpath,sheet);
            col=size(app.data,2);
            if col<2
                errordlg('Input data should have 2 or 3 columns')
                return
            end
            
            x = app.data(:,1);
            C = app.data(:,2);
            [x,I]=sort(x); app.data(:,1)=x;
            C=C(I); app.data(:,2)=C;
            
            figure('Name', 'diffusion data')
            if col==3
                err=app.data(:,3);
                errorbar(x, C, err);
            else
                plot(x, C, '-o');
            end
            xlabel('X')
            ylabel('Concentration')
            title('diffusion data')
            
            type = app.DiffusionprofileDropDown.Value;
            switch type
                case 'I'
                    app.flatpeaktroughinprofileILCheckBox.Enable='on';
                    app.C1EditField.Value=min(C);
                    app.C2EditField.Value=max(C);
                    app.C3EditField.Value=Inf;
                    app.C3EditField.Enable='off';
                case 'J'
                    app.flatpeaktroughinprofileILCheckBox.Enable='on';
                    app.C1EditField.Value=min(C);
                    app.C2EditField.Value=max(C);
                    app.C3EditField.Value=Inf;
                    app.C3EditField.Enable='off';
                case 'K'
                    app.C3EditField.Enable='on';
                    app.flatpeaktroughinprofileILCheckBox.Enable='on';
                    app.C1EditField.Value=min(C);
                    app.C2EditField.Value=max(C);
                    app.C3EditField.Value=max([C(1),C(end)]);
                case 'L'
                    app.C3EditField.Enable='on';
                    app.flatpeaktroughinprofileILCheckBox.Enable='on';
                    app.C1EditField.Value=min(C);
                    app.C2EditField.Value=max(C);
                    app.C3EditField.Value=min([C(1),C(end)]);
                otherwise
                    app.C1EditField.Value=min(C);
                    app.C2EditField.Value=max(C);
                    app.C3EditField.Value=Inf;
                    app.C3EditField.Enable='off';
                    app.flatpeaktroughinprofileILCheckBox.Value=1;
                    app.flatpeaktroughinprofileILCheckBox.Enable='off';
            end
        end

        % Button pushed function: StartModelingButton
        function StartModelingButtonPushed(app, event)
            if isempty(app.data)
                return
            end
            
            isC0=1; %appropriate C0 range when no flat peak/trough in profile I-L
            type=app.DiffusionprofileDropDown.Value;
            unittype=app.XunitDropDown.Value;
            T = app.InitialtemperatureEditField.Value; %initial temperature, K
            eT = app.TemperatureerrorEditField.Value; %initial temperature uncertainty, K
            dT_dt = app.CoolingrateEditField.Value; %cooling rate
            ratetype=app.RatetypeDropDown.Value;
            C1 = app.C1EditField.Value; %intial concentration (left)
            C2 = app.C2EditField.Value; %intial concentration (middle)
            C3 = app.C3EditField.Value; %intial concentration (right)
            ttrials = app.TrialsfortEditField.Value;
            flat=app.flatpeaktroughinprofileILCheckBox.Value;
            D0num=app.D0EditField.Value;
            Hnum=app.HEditField.Value;
            
            col=size(app.data,2);
            x = app.data(:,1);
            C = app.data(:,2);
            if col==3
                err=app.data(:,3);
            else
                err=ones(length(x),1);
            end
            weights=1./(err.^2);
            
            if strcmp(unittype, 'um')
                x=x/1e6; %unit m
            end
            switch ratetype
                case 'K/Ma'
                    dT_dt=dT_dt/1e6/365/24/3600;
                case 'K/year'
                    dT_dt=dT_dt/365/24/3600;
            end
            %make sure from left to right, C1, C2, C3
            try
                switch type
                    case 'I'
                        [k, ek, paras, isC0] = Diffsusion_solution_IL(x, C, C1, C2, C1, type, flat, weights);
                    case 'J'
                        [k, ek, paras, isC0] = Diffsusion_solution_IL(x, C, C2, C1, C2, type, flat, weights);
                    case 'K'
                        if C(1)>C(end)
                            temp=C1;
                            C1=C3;
                            C3=temp;
                        end
                        [k, ek, paras, isC0] = Diffsusion_solution_IL(x, C, C1, C2, C3, type, flat, weights);
                    case 'L'
                        if C(1)<C(end)
                            temp=C2;
                            C2=C3;
                            C3=temp;
                        end
                        [k, ek, paras, isC0] = Diffsusion_solution_IL(x, C, C2, C1, C3, type, flat, weights);
                    otherwise
                        [k, ek, paras] = Diffsusion_solution_AH(x, C, C1, C2, type, weights);
                end
                if ~isC0 %inappropriate C0 range when no flat peak/trough in profile I-L
                    return
                end
                if isnan(k)
                    msgbox('No fitting results can be found, please check input parameters')
                    return
                end
                [t, et]= solve_time_dependent_D(k, ek, T, eT, dT_dt, ttrials, D0num, Hnum);
                %define time unit
                if t(end,end)>(1e6*365*24*3600) %Myr
                    t=t/(1e6*365*24*3600);
                    tname='Timescale (Myr)';
                elseif t(end,end)>(1e3*365*24*3600) %kyr
                    t=t/(1e3*365*24*3600);
                    tname='Timescale (kyr)';
                elseif t(end,end)>(365*24*3600) %year
                    t=t/(365*24*3600);
                    tname='Timescale (year)';
                elseif t(end,end)>(24*3600) %day
                    t=t/(24*3600);
                    tname='Timescale (day)';
                elseif t(end,end)>3600 %hour
                    t=t/3600;
                    tname='Timescale (hour)';
                elseif t(end,end)>60 %minute
                    t=t/60;
                    tname='Timescale (minute)';
                else %second
                    tname='Timescale (second)';
                end
                %plot timescales
                eb1=et(:,1).*t/100; %absolute error
                eb2=et(:,2).*t/100;
                figure('Name', 'Timescales')
                errorbar(1:1:length(t), t, eb1, eb2, 'Marker','s', 'LineStyle', 'none');
                xlabel('Trials')
                ylabel(tname);
                xticks(0:1:(length(t)+1));
                xlim([0, length(t)+1]);
                startindex=regexp(tname, '(');
                endindex=regexp(tname, ')');
                unitname=tname(startindex+1:endindex-1);
                if length(t)>1
                    title(['Timescale ranges from ', num2str(t(1), '%.f'), '_{', num2str(eb1(1), '%.f'), '}', '^{+', num2str(eb2(1), '%.f'), '}, to ',...
                        num2str(t(end), '%.f'), '_{', num2str(eb1(end), '%.f'), '}', '^{', num2str(eb2(end), '%.f'), '} ', unitname]);
                else
                    title(['Timescale = ', num2str(t, '%.f'), '_{', num2str(eb1, '%.f'), '}', '^{+', num2str(eb2, '%.f'), '} ', unitname]);
                end
                
                N=1:1:length(k); N=N';
                if contains(type, {'I','J','K','L'})
                    output=[N, paras, k, t, et];
                    Title={'Trials', 'C0', 'x0', 'h', 'R2', 'k', tname, '-2s%', '+2s%'};
                else
                    output=[N, paras, k, t, et];
                    Title={'Trials', 'R2', 'k', tname, '-2s%', '+2s%'};
                end
                output=num2cell(output);
                [FileName,PathName,~] = uiputfile('*.txt','Save Modeling Result','Modeling Result');
                if ~FileName
                    return
                end
                outputfile=fullfile(PathName,FileName);
                dlmcell(outputfile, Title)
                dlmcell(outputfile, output, '-a')
            catch exc
                errorMsg=exc.message;
                errordlg(errorMsg);
            end
        end

        % Value changed function: DiffusionprofileDropDown
        function DiffusionprofileDropDownValueChanged(app, event)
            type = app.DiffusionprofileDropDown.Value;
            switch type
                case 'I'
                    app.flatpeaktroughinprofileILCheckBox.Enable='on';
                    app.C3EditField.Enable='off';
                case 'J'
                    app.flatpeaktroughinprofileILCheckBox.Enable='on';
                    app.C3EditField.Enable='off';
                case 'K'
                    app.C3EditField.Enable='on';
                    app.flatpeaktroughinprofileILCheckBox.Enable='on';
                case 'L'
                    app.C3EditField.Enable='on';
                    app.flatpeaktroughinprofileILCheckBox.Enable='on';
                otherwise
                    app.C3EditField.Enable='off';
                    app.flatpeaktroughinprofileILCheckBox.Value=1;
                    app.flatpeaktroughinprofileILCheckBox.Enable='off';
            end
        end

        % Button pushed function: SeeoptionsButton
        function SeeoptionsButtonPushed(app, event)
            str='Diffusion profile options.jpg';
            if exist(str, 'file')
                figure('Name', 'Diffusion profile options')
                imshow(str)
            else
                errordlg("Cannot find the orginal file 'Diffusion profile options.jpg'")
                return
            end
        end

        % Button pushed function: ReadanotherDexcelButton
        function ReadanotherDexcelButtonPushed(app, event)
            filestyle={'*.xlsx';'*.xls'};
            [filename, pathname]=uigetfile(filestyle,'Select a diffusion coefficient excel','MultiSelect','off');
            [~,b]=size(filename);
            if  b==1 && filename==0
                return;
            elseif ~iscell(filename)
                [~,~,ext]=fileparts(filename);
                switch ext
                    case '.xlsx'
                        str=[pathname filename];
                    case '.xls'
                        str=[pathname filename];
                    otherwise
                        return;
                end
            end
            [~, sheetname] = xlsfinfo(str);
            [~,cs]=size(sheetname);
            if cs==1
                sheet=sheetname{1};
                v=1;
            else
                [st,v]=listdlg('PromptString','Select a sheet','SelectionMode','single','ListString',sheetname);
                sheet=sheetname{st};
            end
            if v
                [~, ~, raw]=xlsread(str,sheet);
            else
                return;
            end
            app.name=join(raw(2:end,1:2));
            D1=raw(2:end,3);
            app.D0=cell2mat(D1);
            H1=raw(2:end,4);
            app.H=cell2mat(H1);
            app.OptionsDropDown.Items=app.name;
            value = app.OptionsDropDown.Value;
            site=find(strcmp(app.name,value));
            app.D0EditField.Value=app.D0(site);
            app.HEditField.Value=app.H(site);
        end

        % Value changed function: OptionsDropDown
        function OptionsDropDownValueChanged(app, event)
            value = app.OptionsDropDown.Value;
            site=find(strcmp(app.name,value));
            app.D0EditField.Value=app.D0(site);
            app.HEditField.Value=app.H(site);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DiffuserGUI = uifigure('Visible', 'off');
            app.DiffuserGUI.Name = 'Diffuser';
            app.DiffusionmodelingPanel = uipanel(app.DiffuserGUI);
            app.DiffusionmodelingPanel.Title = 'Diffusion modeling';
            app.InitialtemperatureEditFieldLabel = uilabel(app.DiffusionmodelingPanel);
            app.InitialtemperatureEditFieldLabel.Text = 'Initial temperature';
            app.InitialtemperatureEditField = uieditfield(app.DiffusionmodelingPanel, 'numeric');
            app.InitialtemperatureEditField.ValueDisplayFormat = '%.2f';
            app.InitialtemperatureEditField.Value = 1073;
            app.CoolingrateEditField_2Label = uilabel(app.DiffusionmodelingPanel);
            app.CoolingrateEditField_2Label.Text = 'Cooling rate';
            app.CoolingrateEditField = uieditfield(app.DiffusionmodelingPanel, 'numeric');
            app.C1EditFieldLabel = uilabel(app.DiffusionmodelingPanel);
            app.C1EditFieldLabel.Text = 'C1';
            app.C1EditField = uieditfield(app.DiffusionmodelingPanel, 'numeric');
            app.C1EditField.ValueDisplayFormat = '%.2f';
            app.C2EditFieldLabel = uilabel(app.DiffusionmodelingPanel);
            app.C2EditFieldLabel.Text = 'C2';
            app.C2EditField = uieditfield(app.DiffusionmodelingPanel, 'numeric');
            app.C2EditField.ValueDisplayFormat = '%.2f';
            app.C2EditField.Value = 100;
            app.KLabel = uilabel(app.DiffusionmodelingPanel);
            app.KLabel.Text = 'K';
            app.StartModelingButton = uibutton(app.DiffusionmodelingPanel, 'push');
            app.StartModelingButton.ButtonPushedFcn = createCallbackFcn(app, @StartModelingButtonPushed, true);
            app.StartModelingButton.Text = 'Start Modeling';
            app.C3EditFieldLabel = uilabel(app.DiffusionmodelingPanel);
            app.C3EditFieldLabel.Text = 'C3';
            app.C3EditField = uieditfield(app.DiffusionmodelingPanel, 'numeric');
            app.C3EditField.ValueDisplayFormat = '%.2f';
            app.DiffusionprofileDropDownLabel = uilabel(app.DiffusionmodelingPanel);
            app.DiffusionprofileDropDownLabel.Text = 'Diffusion profile';
            app.DiffusionprofileDropDown = uidropdown(app.DiffusionmodelingPanel);
            app.DiffusionprofileDropDown.Items = {'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L'};
            app.DiffusionprofileDropDown.ValueChangedFcn = createCallbackFcn(app, @DiffusionprofileDropDownValueChanged, true);
            app.DiffusionprofileDropDown.Value = 'A';
            app.XunitDropDownLabel = uilabel(app.DiffusionmodelingPanel);
            app.XunitDropDownLabel.Text = 'X unit';
            app.XunitDropDown = uidropdown(app.DiffusionmodelingPanel);
            app.XunitDropDown.Items = {'um', 'm'};
            app.XunitDropDown.Value = 'um';
            app.SeeoptionsButton = uibutton(app.DiffusionmodelingPanel, 'push');
            app.SeeoptionsButton.ButtonPushedFcn = createCallbackFcn(app, @SeeoptionsButtonPushed, true);
            app.SeeoptionsButton.Text = 'See options';
            app.flatpeaktroughinprofileILCheckBox = uicheckbox(app.DiffusionmodelingPanel);
            app.flatpeaktroughinprofileILCheckBox.Enable = 'off';
            app.flatpeaktroughinprofileILCheckBox.Text = 'flat peak/trough in profile I-L';
            app.flatpeaktroughinprofileILCheckBox.Value = true;
            app.minconcentrationLabel = uilabel(app.DiffusionmodelingPanel);
            app.minconcentrationLabel.Text = 'min concentration';
            app.maxconcentrationLabel = uilabel(app.DiffusionmodelingPanel);
            app.maxconcentrationLabel.Text = 'max concentration';
            app.RatetypeDropDown = uidropdown(app.DiffusionmodelingPanel);
            app.RatetypeDropDown.Items = {'K/Ma', 'K/year', 'K/second'};
            app.RatetypeDropDown.Value = 'K/Ma';
            app.sigmaLabel = uilabel(app.DiffusionmodelingPanel);
            app.sigmaLabel.Text = '2 sigma';
            app.TemperatureerrorEditFieldLabel = uilabel(app.DiffusionmodelingPanel);
            app.TemperatureerrorEditFieldLabel.Text = 'Temperature error';
            app.TemperatureerrorEditField = uieditfield(app.DiffusionmodelingPanel, 'numeric');
            app.TemperatureerrorEditField.ValueDisplayFormat = '%.2f';
            app.TemperatureerrorEditField.Value = 20;
            app.TrialsfortEditFieldLabel = uilabel(app.DiffusionmodelingPanel);
            app.TrialsfortEditFieldLabel.Text = 'Trials for t';
            app.TrialsfortEditField = uieditfield(app.DiffusionmodelingPanel, 'numeric');
            app.TrialsfortEditField.ValueDisplayFormat = '%.0f';
            app.TrialsfortEditField.Value = 1000;
            app.whencoolingLabel = uilabel(app.DiffusionmodelingPanel);
            app.whencoolingLabel.Text = 'when cooling';
            app.usedinprofileKLLabel = uilabel(app.DiffusionmodelingPanel);
            app.usedinprofileKLLabel.Text = 'used in profile K-L';
            app.DatainputPanel = uipanel(app.DiffuserGUI);
            app.DatainputPanel.Title = 'Data input';
            app.ReaddataButton = uibutton(app.DatainputPanel, 'push');
            app.ReaddataButton.ButtonPushedFcn = createCallbackFcn(app, @ReaddataButtonPushed, true);
            app.ReaddataButton.Text = 'Read data';
            app.SelectexcelButton = uibutton(app.DatainputPanel, 'push');
            app.SelectexcelButton.ButtonPushedFcn = createCallbackFcn(app, @SelectexcelButtonPushed, true);
            app.SelectexcelButton.Text = 'Select excel';
            app.SheetListBoxLabel = uilabel(app.DatainputPanel);
            app.SheetListBoxLabel.Text = 'Sheet';
            app.SheetListBox = uilistbox(app.DatainputPanel);
            app.SheetListBox.Items = {'Sheet 1', 'Sheet 2', 'Sheet 3', 'Sheet 4'};
            app.SheetListBox.Value = 'Sheet 1';
            app.DiffusionCoefficientDPanel = uipanel(app.DiffuserGUI);
            app.DiffusionCoefficientDPanel.Title = 'Diffusion Coefficient (D)';
            app.ReadanotherDexcelButton = uibutton(app.DiffusionCoefficientDPanel, 'push');
            app.ReadanotherDexcelButton.ButtonPushedFcn = createCallbackFcn(app, @ReadanotherDexcelButtonPushed, true);
            app.ReadanotherDexcelButton.Text = 'Read another D excel';
            app.OptionsDropDownLabel = uilabel(app.DiffusionCoefficientDPanel);
            app.OptionsDropDownLabel.Text = 'Options';
            app.OptionsDropDown = uidropdown(app.DiffusionCoefficientDPanel);
            app.OptionsDropDown.Items = {'Quartz Ti	Cherniak et al., 2007, Chem Geol', 'Option 2', 'Option 3', 'Option 4'};
            app.OptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @OptionsDropDownValueChanged, true);
            app.OptionsDropDown.Value = 'Quartz Ti	Cherniak et al., 2007, Chem Geol';
            app.D0EditFieldLabel = uilabel(app.DiffusionCoefficientDPanel);
            app.D0EditFieldLabel.Text = 'D0';
            app.D0EditField = uieditfield(app.DiffusionCoefficientDPanel, 'numeric');
            app.D0EditField.Value = Inf;
            app.HLabel = uilabel(app.DiffusionCoefficientDPanel);
            app.HLabel.Text = 'ΔH';
            app.HEditField = uieditfield(app.DiffusionCoefficientDPanel, 'numeric');
            app.HEditField.Value = Inf;
            % Show the figure after all components are created
            app.DiffuserGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Diffuser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DiffuserGUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DiffuserGUI)
        end
    end
end